classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FjlnvEditField             matlab.ui.control.EditField
        FjlnvEditFieldLabel        matlab.ui.control.Label
        TallzsButton               matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        SzaggatottvonalakCheckBox  matlab.ui.control.CheckBox
        PontozottvonalakCheckBox   matlab.ui.control.CheckBox
        SVGexporttpusaButtonGroup  matlab.ui.container.ButtonGroup
        StatikusexportButton       matlab.ui.control.RadioButton
        InteraktvexportButton      matlab.ui.control.RadioButton
        ExportlsButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fname = '';
        dname = '';
        dottedEnabled = false;
        dashedEnabled = false;
        interactive = true;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExportlsButton
        function ExportlsButtonPushed(app, event)
            %try
                exportsvg(gca, strcat(app.dname,app.fname), app.dashedEnabled, app.dottedEnabled, app.interactive);
            %catch e
                % Ki kéne írni e-t
            %end
            disp(app.dname);
        end

        % Button pushed function: TallzsButton
        function TallzsButtonPushed(app, event)
            app.dname = uigetdir(matlabroot,'MATLAB Root Folder');
        end

        % Value changed function: PontozottvonalakCheckBox
        function PontozottvonalakCheckBoxValueChanged(app, event)
            app.dottedEnabled = app.PontozottvonalakCheckBox.Value;
        end

        % Value changed function: SzaggatottvonalakCheckBox
        function SzaggatottvonalakCheckBoxValueChanged(app, event)
            app.dashedEnabled = app.SzaggatottvonalakCheckBox.Value;
        end

        % Selection changed function: SVGexporttpusaButtonGroup
        function SVGexporttpusaButtonGroupSelectionChanged(app, event)
            selectedButton = app.SVGexporttpusaButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Statikus export'
                    app.interactive = false;
                case 'Interaktív export'
                    app.interactive = true;
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            %if()
            %copyobj(get(gca,'Parent'), app.UIAxes);
        end

        % Value changed function: FjlnvEditField
        function FjlnvEditFieldValueChanged(app, event)
            app.fname = app.FjlnvEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ExportlsButton = uibutton(app.UIFigure, 'push');
            app.ExportlsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlsButtonPushed, true);
            app.ExportlsButton.Text = 'Exportálás';
            app.SVGexporttpusaButtonGroup = uibuttongroup(app.UIFigure);
            app.SVGexporttpusaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SVGexporttpusaButtonGroupSelectionChanged, true);
            app.SVGexporttpusaButtonGroup.Title = 'SVG export típusa';
            app.InteraktvexportButton = uiradiobutton(app.SVGexporttpusaButtonGroup);
            app.InteraktvexportButton.Text = 'Interaktív export';
            app.InteraktvexportButton.Value = true;
            app.StatikusexportButton = uiradiobutton(app.SVGexporttpusaButtonGroup);
            app.StatikusexportButton.Text = 'Statikus export';
            app.PontozottvonalakCheckBox = uicheckbox(app.UIFigure);
            app.PontozottvonalakCheckBox.ValueChangedFcn = createCallbackFcn(app, @PontozottvonalakCheckBoxValueChanged, true);
            app.PontozottvonalakCheckBox.Text = 'Pontozott vonalak';
            app.SzaggatottvonalakCheckBox = uicheckbox(app.UIFigure);
            app.SzaggatottvonalakCheckBox.ValueChangedFcn = createCallbackFcn(app, @SzaggatottvonalakCheckBoxValueChanged, true);
            app.SzaggatottvonalakCheckBox.Text = 'Szaggatott vonalak';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Figyelem! A vonal stílusok'; 'elképzelhető, hogy '; 'teljesítményi problémákhoz'; 'vezethetnek, vagy nem az '; 'egész vonalat fedik le.'};
            app.TallzsButton = uibutton(app.UIFigure, 'push');
            app.TallzsButton.ButtonPushedFcn = createCallbackFcn(app, @TallzsButtonPushed, true);
            app.TallzsButton.Text = 'Tallózás';
            app.FjlnvEditFieldLabel = uilabel(app.UIFigure);
            app.FjlnvEditFieldLabel.Text = 'Fájl név:';
            app.FjlnvEditField = uieditfield(app.UIFigure, 'text');
            app.FjlnvEditField.ValueChangedFcn = createCallbackFcn(app, @FjlnvEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end