classdef GeomatplotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Toolbar          matlab.ui.container.Toolbar
        Tools            matlab.ui.container.toolbar.ToggleTool
        Button           matlab.ui.control.Button
        ToolsPanel       matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        Midpoint2Button  matlab.ui.control.Button
        Circle2Button    matlab.ui.control.Button
        Circle3Button    matlab.ui.control.Button
        LineButton       matlab.ui.control.Button
        SegmentButton    matlab.ui.control.Button
        MoveButton       matlab.ui.control.Button
        PointButton      matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        model
    end
    
    methods (Access = public)
        
        function openPropertyEditor(app,roi)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.model = GeomatplotGuiModel(app.UIAxes);
            app.Tools.Tooltip = 'Open tools menu';
        end

        % Callback function: Tools
        function ToolsClicked(app, event)
            app.ToolsPanel.Visible = ~app.ToolsPanel.Visible;
        end

        % Button pushed function: PointButton
        function PointButtonPushed(app, event)
            app.model.setState('point');
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            app.model.setState('select');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cursorPosition = get(0, 'PointerLocation');
        
            % Open the mini property editor at the cursor position
            MiniPropertyEditor(app, cursorPosition);
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            app.model.setState('segment');
        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            app.model.setState('line');
        end

        % Button pushed function: Circle3Button
        function Circle3ButtonPushed(app, event)
            app.model.setState('circle3');
        end

        % Button pushed function: Circle2Button
        function Circle2ButtonPushed(app, event)
            app.model.setState('circle2');
        end

        % Button pushed function: Midpoint2Button
        function Midpoint2ButtonPushed(app, event)
            app.model.setState('midpoint2');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Tools = uitoggletool(app.Toolbar);
            app.Tools.ClickedCallback = createCallbackFcn(app, @ToolsClicked, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Main View')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ToolsPanel = uipanel(app.UIFigure);
            app.ToolsPanel.Title = 'Tools';
            app.GridLayout = uigridlayout(app.ToolsPanel);
            app.PointButton = uibutton(app.GridLayout, 'push');
            app.PointButton.ButtonPushedFcn = createCallbackFcn(app, @PointButtonPushed, true);
            app.PointButton.Icon = fullfile(pathToMLAPP, 'resources', 'pointgmp.png');
            app.PointButton.Tooltip = {'Place a point'};
            app.PointButton.Text = '';
            app.MoveButton = uibutton(app.GridLayout, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Icon = fullfile(pathToMLAPP, 'resources', 'cursorGMP.png');
            app.MoveButton.Tooltip = {'Move'};
            app.MoveButton.Text = '';
            app.SegmentButton = uibutton(app.GridLayout, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Icon = fullfile(pathToMLAPP, 'resources', 'segmentGMP.png');
            app.SegmentButton.Tooltip = {'Make a Segment'};
            app.SegmentButton.Text = '';
            app.LineButton = uibutton(app.GridLayout, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Icon = fullfile(pathToMLAPP, 'resources', 'lineGMP.png');
            app.LineButton.Tooltip = {'Make a Segment'};
            app.LineButton.Text = '';
            app.Circle3Button = uibutton(app.GridLayout, 'push');
            app.Circle3Button.ButtonPushedFcn = createCallbackFcn(app, @Circle3ButtonPushed, true);
            app.Circle3Button.Icon = fullfile(pathToMLAPP, 'resources', 'Circle3GMP.png');
            app.Circle3Button.Tooltip = {'Make a Circle by 3 points'};
            app.Circle3Button.Text = '';
            app.Circle2Button = uibutton(app.GridLayout, 'push');
            app.Circle2Button.ButtonPushedFcn = createCallbackFcn(app, @Circle2ButtonPushed, true);
            app.Circle2Button.Icon = fullfile(pathToMLAPP, 'resources', 'Circle2GMP.png');
            app.Circle2Button.Tooltip = {'Make a Circle by 2 points'};
            app.Circle2Button.Text = '';
            app.Midpoint2Button = uibutton(app.GridLayout, 'push');
            app.Midpoint2Button.ButtonPushedFcn = createCallbackFcn(app, @Midpoint2ButtonPushed, true);
            app.Midpoint2Button.Icon = fullfile(pathToMLAPP, 'resources', 'midpointGMP.png');
            app.Midpoint2Button.Tooltip = {'Create Midpoint between two points'};
            app.Midpoint2Button.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeomatplotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end